classdef aggiemotivation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GOButton              matlab.ui.control.Button
        AggieMotivationLabel  matlab.ui.control.Label
        EditField             matlab.ui.control.EditField
        EnternameLabel        matlab.ui.control.Label
        Image                 matlab.ui.control.Image
    end

    
    properties (Access = private)
        name;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GOButton
        function GOButtonPushed(app, event)
           

background = imread('scenebackground.png');
fig=figure;
image(background)
set(fig,'Position',[400 50 800 800])
axis off equal

set(fig,'KeyPressFcn',@Pressed)
scene=0;

line{1}='Hello Aggie! Just letting you know you have to click ENTER to progress.';

line{2}='How are you doing today?';
line{3}='Its me, your Chancellor, Gary May.';

line{4}='If you are seeing this message, it means that you might have been having a hard time in your classes.';
line{5}='It is also very hard to manage other aspects of your life while also being on top of school.';

line{6}='Lucky for you, as your Chancellor, I am here for you.';
line{7}='And if you ever feel like bad about school every again, you can always launch this program.';

 line{8}="I love you, "+app.name+"."+" I hope you will prosper in school and set yourself up for a fruitful future.";

 line{9}='Best Regards, Chancellor May.';
long = length(line);
counter = 1;

size = [.175 0.15 .69 .2];
a = annotation('textbox',size,'String',line{1},'LineStyle','none','FontSize',10);

while scene==0
    drawnow
end
    function Pressed(~,data)
        switch data.Character
            case 13
                counter=counter+1;
                speech=line{1};
                peech=line{2};
                eech=line{4};
                ech=line{6};
                ch=line{8};
                h=line{9};
                if counter <= long
                    for i=2:counter
                        if i<2
                            speech=[speech,' ',line{i}];
                            set(a,'String',speech);
                        elseif i==2
                            set(a,'String',peech);
                         elseif (i < 4) && (i > 2)
                             peech=[peech,' ',line{i}];
                            set(a,'String',peech);
                        elseif i==4
                            set(a,'String',eech);
                        elseif (i < 6) && (i > 4)
                            eech=[eech,' ',line{i}];
                            set(a,'String',eech);
                        elseif i==6
                            set(a,'String',ech);
                        elseif (i < 8) && (i > 6)
                            ech=[ech,' ',line{i}];
                            set(a,'String',ech);
                            elseif i==8
                            set(a,'String',ch);
                        elseif i==9
                            set(a,'String',h);
                        end
                    end
                else
                    close all
                    clear all
                    scene = 1;
                end
    end
    end

        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            app.name = app.EditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'gunrock.png';
            app.EnternameLabel = uilabel(app.UIFigure);
            app.EnternameLabel.Text = 'Enter name:';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.AggieMotivationLabel = uilabel(app.UIFigure);
            app.AggieMotivationLabel.Text = 'Aggie Motivation';
            app.GOButton = uibutton(app.UIFigure, 'push');
            app.GOButton.ButtonPushedFcn = createCallbackFcn(app, @GOButtonPushed, true);
            app.GOButton.Text = 'GO';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = aggiemotivation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end